classdef RelIntOrthDip_GUI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure            matlab.ui.Figure
		GridLayout          matlab.ui.container.GridLayout
		LeftPanel           matlab.ui.container.Panel
		GridLayout3         matlab.ui.container.GridLayout
		GridLayout8         matlab.ui.container.GridLayout
		bEditField          matlab.ui.control.NumericEditField
		bEditFieldLabel     matlab.ui.control.Label
		cEditField          matlab.ui.control.NumericEditField
		cEditFieldLabel     matlab.ui.control.Label
		GridLayout7         matlab.ui.container.GridLayout
		SetPlaneButton      matlab.ui.control.Button
		PlaneabcLabel       matlab.ui.control.Label
		aEditField          matlab.ui.control.NumericEditField
		aEditFieldLabel     matlab.ui.control.Label
		CalculateButton     matlab.ui.control.Button
		GridLayout6         matlab.ui.container.GridLayout
		PhiEditField        matlab.ui.control.NumericEditField
		PhiEditFieldLabel   matlab.ui.control.Label
		ThetaEditField      matlab.ui.control.NumericEditField
		thetaLabel          matlab.ui.control.Label
		GridLayout5         matlab.ui.container.GridLayout
		StartupButton       matlab.ui.control.Button
		ModeDropDown        matlab.ui.control.DropDown
		ModeDropDownLabel   matlab.ui.control.Label
		NPtsEditField       matlab.ui.control.NumericEditField
		NPtsEditFieldLabel  matlab.ui.control.Label
		UIAxes2             matlab.ui.control.UIAxes
		UICube              matlab.ui.control.UIAxes
		RightPanel          matlab.ui.container.Panel
		GridLayout4         matlab.ui.container.GridLayout
		UIAxes              matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = private)
		h % ViewMode
		UIPol
	end

	methods (Access = private)

		function results = rotate_polvec_matrix(app)

		end

		function u = light_prop_vect(app, theta, phi)
			u_x = sin(theta).*cos(phi);
			u_y = sin(theta).*sin(phi);
			u_z = cos(theta);
			u = [u_x u_y u_z];
		end

		function e = light_pol_vect(app, u)
			e_unnorm = [0; -u(3); u(2)];

			e = e_unnorm./norm(e_unnorm);
		end

		function results = rotate_e_around_u(app, e, u, alpha,phi)
			ux = u(1);
			uy = u(2);
			uz = u(3);

			cs = cos(alpha-phi);
			csm = 1 - cs;
			si = sin(alpha-phi);

			rotation_matrix = ...
				[ cs + ux^2*csm			ux*uy*csm - uz*si		ux*uz*csm + uy*si	;...
				uy*ux*csm +  uz*si	cs + uy^2*csm			uy*uz*csm-ux*si		;...
				uz*ux*csm-uy*si		uz*uy*csm+ux*si			cs + uz^2*csm		];

			results = rotation_matrix*e;
		end


		function results = integrate_over_all_angles(app, e, u, alpha)

			numpts = app.NPtsEditField.Value
			Alphas = linspace(0,2*pi,numpts);
			AllInts = zeros(3,numpts);

			for i = 1:length(Alphas)
				alpha = Alphas(i);
				thise = rotate_e_around_u(app,e,u,alpha,phi);

				AllInts(:,i) = [thise(1)^2 thise(2)^2 thise(3)^2];
			end

		end

		function results = CreateUICube(app)
			cla(app.UICube)
			my_scale = 0.8;
			my_vertices = [0 0 0; 0 1 0; 1 1 0; 1 0 0; 0 0 1; 0 1 1; 1 1 1; 1 0 1].*my_scale - [ones(8,3).*my_scale./2];
			my_faces = [1 2 3 4; 2 6 7 3; 4 3 7 8; 1 5 8 4; 1 2 6 5; 5 6 7 8];

			pp= patch(app.UICube,'Vertices', my_vertices, 'Faces', my_faces, 'FaceColor', 'g');
			pp.HandleVisibility='off';
			pp.HitTest ='on';
			pp.ButtonDownFcn = @CalculateButtonPushed;
			hold(app.UICube,'on')
			quiver3(app.UICube,0,0,0,-1,0,0,'filled','LineWidth',4,'Color',app.UICube.ColorOrder(1,:),'LineStyle',':','DisplayName',' -X')
			quiver3(app.UICube,0,0,0,0,-1,0,'filled','LineWidth',4,'Color',app.UICube.ColorOrder(2,:),'LineStyle',':','DisplayName',' -Y')
			quiver3(app.UICube,0,0,0,0,0,-1,'filled','LineWidth',4,'Color',app.UICube.ColorOrder(3,:),'LineStyle',':','DisplayName',' -Z')
			quiver3(app.UICube,0,0,0,1,0,0,'filled','LineWidth',4,'Color',app.UICube.ColorOrder(1,:),'DisplayName',' X')
			quiver3(app.UICube,0,0,0,0,1,0,'filled','LineWidth',4,'Color',app.UICube.ColorOrder(2,:),'DisplayName',' Y')
			quiver3(app.UICube,0,0,0,0,0,1,'filled','LineWidth',4,'Color',app.UICube.ColorOrder(3,:),'DisplayName',' Z')

			pbaspect(app.UICube,[1 1 1])
			thelim = [-1 1];
			xlim(app.UICube,thelim); ylim(app.UICube,thelim); zlim(app.UICube,thelim);


			xlabel(app.UICube,'X','Color',app.UICube.ColorOrder(1,:),'FontWeight','bold','FontSize',15);
			ylabel(app.UICube,'Y','Color',app.UICube.ColorOrder(2,:),'FontWeight','bold','FontSize',15);
			zlabel(app.UICube,'Z','Color',app.UICube.ColorOrder(3,:),'FontWeight','bold','FontSize',15);


			app.UICube.XRuler.FirstCrossoverValue  = 0; % X crossover with Y axis
			app.UICube.YRuler.FirstCrossoverValue  = 0; % Y crossover with X axis
			app.UICube.ZRuler.FirstCrossoverValue  = 0; % Z crossover with X axis
			app.UICube.ZRuler.SecondCrossoverValue = 0; % Z crossover with Y axis
			app.UICube.XRuler.SecondCrossoverValue = 0; % X crossover with Z axis
			app.UICube.YRuler.SecondCrossoverValue = 0; % Y crossover with Z axis

			set(app.UICube,'XTick',[],'YTick',[],'ZTick',[])
						app.h=rotate3d(app.UICube);%,'on','ActionPostCallback',@GetViewButtonPushed,'UseLegacyExplorationModes',false);
						app.h.Enable='on';

			%assignin('base','hh',app.h)

						%app.h.ActionPostCallback = @GetViewButtonPushed;
			%app.h.FigureHandle = app.UICube;
			%app.h.UseLegacyExplorationModes='off';
			legend(app.UICube,'Location','westoutside','NumColumns',2,'EdgeColor','none')
		end


		function results = updateView(app)
			%thetavalue = mod(app.ThetaEditField.Value,180);
			%phivalue = mod(app.PhiEditField.Value,360);

			phivalue = app.PhiEditField.Value-270;
			thetavalue = app.ThetaEditField.Value;
			%[phivalue;thetavalue]
			phivalue = mod(phivalue,360);
			newval = phitheta2azel([phivalue;thetavalue],false)	;
			sprintf('Az %d El %d',newval)
			%newval = light_prop_vect(app, thetavalue, phivalue);

			%			newval(1) = mod(newval(1)
			%view(app.UICube,newval);
			set(app.UICube, 'View', newval);
			assignin('base','da',newval)
		end


		function UpdateLinePlot(app)
			theta_view = deg2rad(mod(app.ThetaEditField.Value,180));
			phi_view = deg2rad(mod(app.PhiEditField.Value,360));


			%			theta_view = deg2rad(app.ThetaEditField.Value);
			%			phi_view = deg2rad(app.PhiEditField.Value);


			%theta = pi-theta_view;
			phi = phi_view;


			theta = theta_view;
			%phi = phi_view;

			u = light_prop_vect(app,theta,phi);

			e = light_pol_vect(app,u);

			numpts = app.NPtsEditField.Value;

			Alphas = linspace(0,2*pi,numpts);
			A = zeros(length(Alphas),3);
			for i=1:numpts
				alpha = Alphas(i);
				thise = rotate_e_around_u(app,e,u,alpha,phi);
				A(i,:) = [thise(1).^2 thise(2).^2 thise(3).^2];
			end
			assignin('base','Alphas',Alphas)
			assignin('base','A',A)
			plot(app.UIAxes,rad2deg(Alphas),A,'.-')


			polarplot(app.UIPol,Alphas, A,'.-')

			app.UIPol.ThetaZeroLocation = 'top';
			%xlim(app.UIAxes,[-Inf Inf])
		end
	end

	methods (Access = public)

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			CreateUICube(app)

			%%% create polar axes
			app.UIPol = polaraxes(app.GridLayout4);
			app.UIPol.ThetaZeroLocation = 'top';
			app.UIPol.Layout.Row = [5 8];
			app.UIPol.Layout.Column = [1 2];


			UpdateLinePlot(app)
		end

		% Button pushed function: StartupButton
		function StartupButtonPushed(app, event)
			%startupFcn(app)
			CreateUICube(app)
			
			UpdateLinePlot(app)
% 			ttmp=azel2phitheta(reshape(app.UICube.View,[2 1]),false);
% 			app.PhiEditField.Value = ttmp(1);
% 			app.ThetaEditField.Value = ttmp(2);
		end

		% Value changed function: ThetaEditField
		function ThetaEditFieldValueChanged(app, event)
			sprintf('Theta %d',app.ThetaEditField.Value)
			updateView(app)
			UpdateLinePlot(app)
		end

		% Value changed function: PhiEditField
		function PhiEditFieldValueChanged(app, event)
			%value = app.PhiEditField.Value;
			sprintf('Phi %d',app.PhiEditField.Value)
			updateView(app)
			UpdateLinePlot(app)
		end

		% Button pushed function: CalculateButton
		function CalculateButtonPushed(app, event)
			ggg= app.UICube.View;
			sprintf('Current view az %d el %d',ggg)
			azel=ggg;
			assignin('base','gg',app.UICube)
			%azel = reshape(ggg,[2 1]);
			azel(1) = mod(azel(1),360)-180;
			ttmp=azel2phitheta(reshape(azel,[2,1]),false);

			sprintf('Current view phi %d theta %d',ttmp)
			%ttmp=azel2phitheta([azel(1); azel(2)],false);

			app.PhiEditField.Value = mod(ttmp(1)-270,360);
			app.ThetaEditField.Value = ttmp(2);


			UpdateLinePlot(app)
		end

		% Callback function: SetPlaneButton, aEditField, bEditField, 
		% cEditField
		function SetPlaneButtonPushed(app, event)
			thetarget=[app.aEditField.Value app.bEditField.Value app.cEditField.Value]
			view(app.UICube,thetarget);

			CalculateButtonPushed(app,event)
			switch app.ModeDropDown.Value
				case 'equal intensities'
					disp('eqint')
				case 'Boltzmann'
					disp('Bolt')
				case '4lvl'
					disp('4')
			end
		end

		% Value changed function: NPtsEditField
		function NPtsEditFieldValueChanged(app, event)
			value = app.NPtsEditField.Value;
			UpdateLinePlot(app)
		end

		% Button down function: UICube
		function UICubeButtonDown(app, event)
			SetPlaneButtonPushed(app,event)
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {641, 641};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {437, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout3 = uigridlayout(app.LeftPanel);
			app.UICube = uiaxes(app.GridLayout3);
			xlabel(app.UICube, 'X')
			ylabel(app.UICube, 'Y')
			zlabel(app.UICube, 'Z')
			app.UICube.ButtonDownFcn = createCallbackFcn(app, @UICubeButtonDown, true);
			app.UIAxes2 = uiaxes(app.GridLayout3);
			title(app.UIAxes2, 'Title')
			xlabel(app.UIAxes2, 'X')
			ylabel(app.UIAxes2, 'Y')
			zlabel(app.UIAxes2, 'Z')
			app.GridLayout5 = uigridlayout(app.GridLayout3);
			app.NPtsEditFieldLabel = uilabel(app.GridLayout5);
			app.NPtsEditFieldLabel.Text = 'NPts';
			app.NPtsEditField = uieditfield(app.GridLayout5, 'numeric');
			app.NPtsEditField.ValueChangedFcn = createCallbackFcn(app, @NPtsEditFieldValueChanged, true);
			app.NPtsEditField.Value = 100;
			app.ModeDropDownLabel = uilabel(app.GridLayout5);
			app.ModeDropDownLabel.Text = 'Mode';
			app.ModeDropDown = uidropdown(app.GridLayout5);
			app.ModeDropDown.Items = {'equal intensities', 'Boltzmann', '4lvl'};
			app.ModeDropDown.Value = 'equal intensities';
			app.StartupButton = uibutton(app.GridLayout5, 'push');
			app.StartupButton.ButtonPushedFcn = createCallbackFcn(app, @StartupButtonPushed, true);
			app.StartupButton.Text = 'Startup';
			app.GridLayout6 = uigridlayout(app.GridLayout3);
			app.thetaLabel = uilabel(app.GridLayout6);
			app.thetaLabel.Interpreter = 'latex';
			app.thetaLabel.Text = '$\theta$';
			app.ThetaEditField = uieditfield(app.GridLayout6, 'numeric');
			app.ThetaEditField.Limits = [0 180];
			app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
			app.PhiEditFieldLabel = uilabel(app.GridLayout6);
			app.PhiEditFieldLabel.Interpreter = 'latex';
			app.PhiEditFieldLabel.Text = '$\Phi$';
			app.PhiEditField = uieditfield(app.GridLayout6, 'numeric');
			app.PhiEditField.Limits = [0 360];
			app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
			app.GridLayout7 = uigridlayout(app.GridLayout3);
			app.CalculateButton = uibutton(app.GridLayout7, 'push');
			app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
			app.CalculateButton.Text = 'Calculate';
			app.aEditFieldLabel = uilabel(app.GridLayout7);
			app.aEditFieldLabel.Text = 'a';
			app.aEditField = uieditfield(app.GridLayout7, 'numeric');
			app.aEditField.ValueChangedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.aEditField.Value = 1;
			app.PlaneabcLabel = uilabel(app.GridLayout7);
			app.PlaneabcLabel.Text = 'Plane [abc]';
			app.SetPlaneButton = uibutton(app.GridLayout7, 'push');
			app.SetPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.SetPlaneButton.Text = 'Set Plane';
			app.GridLayout8 = uigridlayout(app.GridLayout3);
			app.cEditFieldLabel = uilabel(app.GridLayout8);
			app.cEditFieldLabel.Text = 'c';
			app.cEditField = uieditfield(app.GridLayout8, 'numeric');
			app.cEditField.ValueChangedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.bEditFieldLabel = uilabel(app.GridLayout8);
			app.bEditFieldLabel.Text = 'b';
			app.bEditField = uieditfield(app.GridLayout8, 'numeric');
			app.bEditField.ValueChangedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout4 = uigridlayout(app.RightPanel);
			app.UIAxes = uiaxes(app.GridLayout4);
			title(app.UIAxes, 'Polarisation measurement')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = RelIntOrthDip_GUI

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end